classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        GenerateaMapButton              matlab.ui.control.Button
        SliderLabel                     matlab.ui.control.Label
        Slider                          matlab.ui.control.Slider
        UITable                         matlab.ui.control.Table
        OrextractmapfromcsvmodelsPanel  matlab.ui.container.Panel
        ChoosefolderwithCSVmodelsButton  matlab.ui.control.Button
        CSVsFolder                      matlab.ui.control.EditField
        ChooseEnsembleForCombinedModelButtonGroup  matlab.ui.container.ButtonGroup
        OriginButton                    matlab.ui.control.RadioButton
        ClusterBasedEnsembleButton      matlab.ui.control.RadioButton
        SelectCSVmodelsnametemplateLabel  matlab.ui.control.Label
        SelectCSVmodelsnametemplateEditField  matlab.ui.control.EditField
        CSVLoadDataButton               matlab.ui.control.Button
        ImportdatafromanncfilePanel     matlab.ui.container.Panel
        NcFile                          matlab.ui.control.EditField
        UploadanNCfileButton            matlab.ui.control.Button
        NCLoadDataButton                matlab.ui.control.Button
        ChooseModelDropDownLabel        matlab.ui.control.Label
        ChooseModelDropDown             matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        tableValues; % Description
        
        xValues=[];
        yValues=[];
        zValues=[];
        pathToNcFile;
        pathToCBEs;
        csvNameTemplate;
        theNcFile;
    end
    
    methods (Access = private)
        
        function generateMap(app, hour)
            cla(app.UIAxes,'reset');
            axis(app.UIAxes,'off');
            ax1 = worldmap('Europe');
            land = shaperead('landareas', 'UseGeoCoords', true);
            geoshow([land.Lat],[land.Lon],'Color','k');
            lakes = shaperead('worldlakes', 'UseGeoCoords', true);
            geoshow(lakes, 'FaceColor', 'blue');
            cities = shaperead('worldcities', 'UseGeoCoords', true);
            geoshow(cities, 'Marker', '.', 'Color', 'red');
            c=colorbar(app.UIAxes);
            c.Label.String = 'Ozone concentration (ppbv )';
            title(c, 'ppbv')
            surfm(app.xValues,app.yValues,app.zValues(:,:,(app.Slider.Value)+1), 'EdgeColor','none','FaceAlpha', 0.8);
            c.Position(4) = 0.6*c.Position(4);
            c.Position(1) = 0.95*c.Position(1);
            c.Position = c.Position + [.05 .2 0 0];
            copyobj(ax1.Children, app.UIAxes);
            fig1 = ax1.Parent;
            delete(fig1);
            
        end
        
        function readNcValuesToTable(app, pathToNcFile)
            app.tableValues= [];
            app.zValues= ncread(pathToNcFile, app.ChooseModelDropDown.Value);
            formatSpec = "Table size from hour no. %d:00 : %s";
            typeof = size(app.zValues);
            matrixSize= "%d x %d";
            a = compose(matrixSize, typeof(1,1),typeof(1,2));
            for i=0 : (length(app.zValues(1,1,:))-1)
                value=compose(formatSpec,i,a);
                app.tableValues=[app.tableValues; value];
            end
            app.xValues = ncread(app.pathToNcFile, 'lat')'; % create X value
            disp(app.pathToNcFile)
            disp(size(app.xValues))
            app.yValues = ncread(app.pathToNcFile, 'lon')';% create Y values
            [app.xValues,app.yValues] = meshgrid(double(app.xValues), double(app.yValues));
            app.Slider.Limits = [0 (length(app.zValues(1,1,:))-1)];
            app.UITable.Data = [app.tableValues];
        end
        
        function readCSVValuesToTable(app, pathToFiles)
            app.tableValues= [];
            app.zValues= [];
            if app.ChooseEnsembleForCombinedModelButtonGroup.SelectedObject.Tag == "1" 
                nameTemplate = '24HR_Orig_*.csv';
                app.SelectCSVmodelsnametemplateEditField.Value = nameTemplate
            elseif app.ChooseEnsembleForCombinedModelButtonGroup.SelectedObject.Tag == "2"
                nameTemplate = '24HR_CBE_*.csv';
                app.SelectCSVmodelsnametemplateEditField.Value = nameTemplate
            end
            disp(pathToFiles)
            dirTemplate = strcat(pathToFiles,app.SelectCSVmodelsnametemplateEditField.Value);
            disp(dirTemplate)
            sprintf("selected button: %s",app.ChooseEnsembleForCombinedModelButtonGroup.SelectedObject.Tag);
            fileDirectory = dir(dirTemplate);
            for k = 1 : length(fileDirectory)
                formatSpec = "Read Z %d : %s";
                file = fileDirectory(k).name;
                Z=[];
                Z = readtable([pathToFiles,file]);
                Z = table2array(Z);
                Z=Z';
                typeof = size(Z);
                matrixSize= "%d x %d";
                a = compose(matrixSize, typeof(1,1),typeof(1,2));
                value=compose(formatSpec,k,a);
                app.tableValues=[app.tableValues; value];
                app.zValues (:,:,k)=[Z];
            end
            app.xValues = 69.95:-0.1:30.05; % create X value
            app.yValues = -24.95:0.1:44.95;%% create Y values
            [app.xValues,app.yValues] = meshgrid(double(app.xValues), double(app.yValues));
            app.Slider.Limits = [1 length(fileDirectory)];
            app.UITable.Data = [app.tableValues];
        end

        function collectModels(app)
            app.theNcFile = ncinfo(app.pathToNcFile)
            variables = {app.theNcFile.Variables.Name};
            app.ChooseModelDropDown.Items ={};
            for i=1 : length(variables)
                app.ChooseModelDropDown.Items = [app.ChooseModelDropDown.Items variables(i)];
        
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GenerateaMapButton
        function GenerateaMapButtonPushed(app, event)
            clf; 
            generateMap(app,app.Slider.Value);
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            newval = app.Slider.Value;                         %get value from the slider
            newval = round(newval);                         %round off this value
            set(app.Slider, 'Value', newval);
            generateMap(app,app.Slider.Value);
        end

        % Button pushed function: UploadanNCfileButton
        function UploadanNCfileButtonPushed(app, event)
            [file, path] = uigetfile('*.nc');
            [app.NcFile.Value, app.pathToNcFile] = deal(fullfile(path, file));
            collectModels(app);
        end

        % Button pushed function: ChoosefolderwithCSVmodelsButton
        function ChoosefolderwithCSVmodelsButtonPushed(app, event)
            path = uigetdir();
            app.CSVsFolder.Value = strcat(path,"/");
          
        end

        % Selection changed function: 
        % ChooseEnsembleForCombinedModelButtonGroup
        function ChooseEnsembleForCombinedModelButtonGroupSelectionChanged(app, event)
            selectedButton = app.ChooseEnsembleForCombinedModelButtonGroup.SelectedObject;
            disp(selectedButton)
        end

        % Value changed function: ChooseModelDropDown
        function ChooseModelDropDownValueChanged(app, event)
            generateMap(app,app.Slider.Value);
            
        end

        % Button pushed function: NCLoadDataButton
        function NCLoadDataButtonPushed(app, event)
            readNcValuesToTable(app, app.NcFile.Value);
        end

        % Button pushed function: CSVLoadDataButton
        function CSVLoadDataButtonPushed(app, event)
            readCSVValuesToTable(app, app.CSVsFolder.Value);
        end

        % Callback function
        function GenerateaMapFromCSVsButtonPushed(app, event)
            generateMap(app, app.Slider.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [0 1];
            app.UIAxes.ZLim = [0 1];
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.GenerateaMapButton = uibutton(app.UIFigure, 'push');
            app.GenerateaMapButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateaMapButtonPushed, true);
            app.GenerateaMapButton.Text = 'Generate a Map';
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [1 25];
            app.Slider.MajorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25];
            app.Slider.MajorTickLabels = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16', '17', '18', '19', '20', '21', '22', '23', '24', '25'};
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.MinorTicks = [1 5 10 15 20];
            app.Slider.Value = 1;
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Read Values:'};
            app.UITable.RowName = {};
            app.OrextractmapfromcsvmodelsPanel = uipanel(app.UIFigure);
            app.OrextractmapfromcsvmodelsPanel.Title = 'Or extract map from csv models';
            app.ChoosefolderwithCSVmodelsButton = uibutton(app.OrextractmapfromcsvmodelsPanel, 'push');
            app.ChoosefolderwithCSVmodelsButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosefolderwithCSVmodelsButtonPushed, true);
            app.ChoosefolderwithCSVmodelsButton.Text = {'Choose folder with '; 'CSV models'};
            app.CSVsFolder = uieditfield(app.OrextractmapfromcsvmodelsPanel, 'text');
            app.CSVsFolder.Value = 'select a name template for csv files';
            app.ChooseEnsembleForCombinedModelButtonGroup = uibuttongroup(app.OrextractmapfromcsvmodelsPanel);
            app.ChooseEnsembleForCombinedModelButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ChooseEnsembleForCombinedModelButtonGroupSelectionChanged, true);
            app.ChooseEnsembleForCombinedModelButtonGroup.Title = 'Choose Ensemble For Combined Model';
            app.OriginButton = uiradiobutton(app.ChooseEnsembleForCombinedModelButtonGroup);
            app.OriginButton.Tag = '1';
            app.OriginButton.Text = 'Origin';
            app.OriginButton.Value = true;
            app.ClusterBasedEnsembleButton = uiradiobutton(app.ChooseEnsembleForCombinedModelButtonGroup);
            app.ClusterBasedEnsembleButton.Tag = '2';
            app.ClusterBasedEnsembleButton.Text = 'Cluster Based Ensemble';
            app.SelectCSVmodelsnametemplateLabel = uilabel(app.OrextractmapfromcsvmodelsPanel);
            app.SelectCSVmodelsnametemplateLabel.Text = 'Select CSV model''s name template:';
            app.SelectCSVmodelsnametemplateEditField = uieditfield(app.OrextractmapfromcsvmodelsPanel, 'text');
            app.CSVLoadDataButton = uibutton(app.OrextractmapfromcsvmodelsPanel, 'push');
            app.CSVLoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @CSVLoadDataButtonPushed, true);
            app.CSVLoadDataButton.Text = {'Load Data'; ''};
            app.ImportdatafromanncfilePanel = uipanel(app.UIFigure);
            app.ImportdatafromanncfilePanel.Title = 'Import data from an nc file';
            app.NcFile = uieditfield(app.ImportdatafromanncfilePanel, 'text');
            app.UploadanNCfileButton = uibutton(app.ImportdatafromanncfilePanel, 'push');
            app.UploadanNCfileButton.ButtonPushedFcn = createCallbackFcn(app, @UploadanNCfileButtonPushed, true);
            app.UploadanNCfileButton.Text = 'Upload an NC file :';
            app.NCLoadDataButton = uibutton(app.ImportdatafromanncfilePanel, 'push');
            app.NCLoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @NCLoadDataButtonPushed, true);
            app.NCLoadDataButton.Text = {'Load Data'; ''};
            app.ChooseModelDropDownLabel = uilabel(app.ImportdatafromanncfilePanel);
            app.ChooseModelDropDownLabel.Text = {'Choose  Model'; ''};
            app.ChooseModelDropDown = uidropdown(app.ImportdatafromanncfilePanel);
            app.ChooseModelDropDown.Items = {};
            app.ChooseModelDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseModelDropDownValueChanged, true);
            app.ChooseModelDropDown.Value = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end